classdef Optimizacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MainTab                    matlab.ui.container.Tab
        EnviarButton               matlab.ui.control.Button
        MtodosButtonGroup          matlab.ui.container.ButtonGroup
        WolfeButton                matlab.ui.control.RadioButton
        BiseccinButton             matlab.ui.control.RadioButton
        AureaButton                matlab.ui.control.RadioButton
        AlgoritmosButtonGroup      matlab.ui.container.ButtonGroup
        CasiNewtonBFGSButton       matlab.ui.control.RadioButton
        CasiNewtonDFPButton        matlab.ui.control.RadioButton
        CauchyButton               matlab.ui.control.RadioButton
        NewtonPuroButton           matlab.ui.control.RadioButton
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        PuntoLabel                 matlab.ui.control.Label
        FuncinEditField            matlab.ui.control.NumericEditField
        FuncinEditFieldLabel       matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        Y2EditField                matlab.ui.control.NumericEditField
        Y2EditFieldLabel           matlab.ui.control.Label
        X2EditField                matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        Y1EditField                matlab.ui.control.NumericEditField
        Y1EditFieldLabel           matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        DominioLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
               fx = app.FuncinEditField.Value;
            initial = [app.XEditField.Value;app.YEditField.Value];
            maxIter = app.IteracionesEditField.Value;
            busquedaLineal = app.AlgoritmosButtonGroup.SelectedObject.Text;
            method = app.MtodosButtonGroupButtonGroup.SelectedObject.Text;
            dom1 = [app.X1EditField.Value , app.X2EditField.Value];
            dom2 = [app.Y1EditField.Value, app.Y2EditField.Value];
            ControllerNonLinear(fx, initial, maxIter, busquedaLineal, method,dom1,dom2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.DominioLabel = uilabel(app.MainTab);
            app.DominioLabel.Text = 'Dominio';
            app.X1EditFieldLabel = uilabel(app.MainTab);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.MainTab, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.MainTab);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.MainTab, 'numeric');
            app.X2EditFieldLabel = uilabel(app.MainTab);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.MainTab, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.MainTab);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.MainTab, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.MainTab);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.MainTab, 'numeric');
            app.FuncinEditFieldLabel = uilabel(app.MainTab);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.MainTab, 'numeric');
            app.PuntoLabel = uilabel(app.MainTab);
            app.PuntoLabel.Text = 'Punto';
            app.XEditFieldLabel = uilabel(app.MainTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.MainTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.MainTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.MainTab, 'numeric');
            app.AlgoritmosButtonGroup = uibuttongroup(app.MainTab);
            app.AlgoritmosButtonGroup.Title = 'Algoritmos';
            app.NewtonPuroButton = uiradiobutton(app.AlgoritmosButtonGroup);
            app.NewtonPuroButton.Text = 'Newton Puro';
            app.NewtonPuroButton.Value = true;
            app.CauchyButton = uiradiobutton(app.AlgoritmosButtonGroup);
            app.CauchyButton.Text = 'Cauchy';
            app.CasiNewtonDFPButton = uiradiobutton(app.AlgoritmosButtonGroup);
            app.CasiNewtonDFPButton.Text = 'Casi Newton  DFP';
            app.CasiNewtonBFGSButton = uiradiobutton(app.AlgoritmosButtonGroup);
            app.CasiNewtonBFGSButton.Text = 'Casi Newton BFGS ';
            app.MtodosButtonGroup = uibuttongroup(app.MainTab);
            app.MtodosButtonGroup.Title = 'Métodos';
            app.AureaButton = uiradiobutton(app.MtodosButtonGroup);
            app.AureaButton.Text = 'Aurea';
            app.AureaButton.Value = true;
            app.BiseccinButton = uiradiobutton(app.MtodosButtonGroup);
            app.BiseccinButton.Text = 'Bisección';
            app.WolfeButton = uiradiobutton(app.MtodosButtonGroup);
            app.WolfeButton.Text = 'Wolfe';
            app.EnviarButton = uibutton(app.MainTab, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Optimizacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end